classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LoadButton                      matlab.ui.control.Button
        LoadbinaryInputchooseallfiles_inputtxtLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        PlotbinaryinputLabel            matlab.ui.control.Label
        FindButton                      matlab.ui.control.Button
        FindBayesoptimalparametervaluesLabel  matlab.ui.control.Label
        LoadResponseLabel               matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        loadsexample_binary_inputLabel  matlab.ui.control.Label
        ChooseperceptualresponsemodelLabel  matlab.ui.control.Label
        PerceptualModelButton           matlab.ui.control.Button
        ResponseModelButton             matlab.ui.control.Button
        notdoneLabel_2                  matlab.ui.control.Label
        notdoneLabel_3                  matlab.ui.control.Label
        DefaultButton_2                 matlab.ui.control.Button
        loadstapas_hgf_binary_configandLabel  matlab.ui.control.Label
        notdoneLabel_4                  matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        ChooseoptimizationalgorithmLabel  matlab.ui.control.Label
        DefaultButton_3                 matlab.ui.control.Button
        loadstapas_hgf_binary_configandLabel_2  matlab.ui.control.Label
        notdoneLabel_5                  matlab.ui.control.Label
        notdoneLabel_6                  matlab.ui.control.Label
        notdoneLabel_7                  matlab.ui.control.Label
        notdoneLabel_8                  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        CheckparameteridentifiabilityLabel  matlab.ui.control.Label
        CheckButton                     matlab.ui.control.Button
        LoadButton_3                    matlab.ui.control.Button
        notdoneLabel_9                  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        CalculateParametersLabel        matlab.ui.control.Label
        notdoneLabel_10                 matlab.ui.control.Label
    end


    properties (Access = public)
        u % input
        response %response
        length_u  % length of input u
        perceptual  % preceptual model
        responsemodel    % response model
        optimalg  % Optmization algorithm
        est       % estimations when fitting model
        omega_1
        omega_2
        sim
        
        
    end


    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
              input = uigetfile;      
              app.u = load(input);
              app.length_u = length(app.u);
              app.notdoneLabel_7.Text = 'done';
              app.notdoneLabel_7.FontColor= 'green';

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            scrsz = get(0,'ScreenSize');
            outerpos = [0.2*scrsz(3),0.7*scrsz(4),0.8*scrsz(3),0.3*scrsz(4)];
            figure('OuterPosition', outerpos)
            plot(app.u, '.', 'Color', [0 0.6 0], 'MarkerSize', 11)
            xlabel('Trial number')
            ylabel('u')
            axis([0, app.length_u, -0.1, 1.1])
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
        diary on;
        diary dia.txt;
        app.responsemodel='tapas_bayes_optimal_binary_config';  
        app.est = tapas_fitModel([],app.u,app.perceptual,app.responsemodel,app.optimalg);
        diary off;
        temp = regexp(fileread('dia.txt'), '\r?\n', 'split');
        app.Label.Text = temp;
        delete dia.txt;
        app.responsemodel='tapas_unitsq_sgm_config';


        end

        % Callback function
        function TextAreaValueChanged(app, event)
        output = fileread('diary');
        textLabel = sprintf('%s', output);
        app.LoadResponseLabel.Text = textLabel;
        end

        % Callback function
        function Omega_2EditField_2ValueChanged(app, event)
            app.omega_1 = app.Omega_2EditField_2.Value;
            
        end

        % Callback function
        function Omega_3EditFieldValueChanged(app, event)
            app.omega_2 = app.Omega_3EditField.Value;
            
        end

        % Callback function
        function SimulateButtonPushed(app, event)
            app.sim = tapas_simModel(app.u,'tapas_hgf_binary',[NaN 0 1 NaN 1 1 NaN 0 0 1 1 NaN app.omega_1 app.omega_2],'tapas_unitsq_sgm',5,12345);
            TextArea_2ValueChanged(app, event)
             
            app.notdoneLabel.Text = 'done';
            app.notdoneLabel.FontColor= 'green';
        end

        % Callback function
        function PlotButton_2Pushed(app, event)
            tapas_hgf_binary_plotTraj(app.sim);
        end

        % Callback function
        function TextArea_2ValueChanged(app, event)
            value = 'done';
            
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
            app.u = load('example_binary_input.txt');
            app.length_u = length(app.u);
            app.notdoneLabel_6.Text = 'done';
            app.notdoneLabel_6.FontColor= 'green';
        end

        % Button pushed function: PerceptualModelButton
        function PerceptualModelButtonPushed(app, event)
            app.perceptual = uigetfile;
            app.perceptual = app.perceptual(1:end-2);
            app.notdoneLabel_2.Text = 'done';
            app.notdoneLabel_2.FontColor= 'green';
        end

        % Button pushed function: ResponseModelButton
        function ResponseModelButtonPushed(app, event)
            app.responsemodel = uigetfile;
            app.responsemodel = app.responsemodel(1:end-2);
            app.notdoneLabel_3.Text = 'done';
            app.notdoneLabel_3.FontColor= 'green';
        end

        % Button pushed function: DefaultButton_2
        function DefaultButton_2Pushed(app, event)
            app.perceptual='tapas_hgf_binary_config';
            app.responsemodel='tapas_unitsq_sgm_config';
            app.notdoneLabel_4.Text='done';
            app.notdoneLabel_4.FontColor= 'green';
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
             app.optimalg = uigetfile;
             app.optimalg = app.optimalg(1:end-2);
             app.notdoneLabel_8.Text = 'done';
             app.notdoneLabel_8.FontColor= 'green';
        end

        % Button pushed function: DefaultButton_3
        function DefaultButton_3Pushed(app, event)
            app.optimalg = 'tapas_quasinewton_optim_config';
            app.notdoneLabel_5.Text = 'done';
            app.notdoneLabel_5.FontColor= 'green';
        end

        % Callback function
        function RecoverButtonPushed(app, event)
            app.est = tapas_fitModel(app.sim.y,app.sim.u,app.perceptual,app.responsemodel,app.optimalg);
        end

        % Callback function
        function RecoverButtonPushed2(app, event)
        diary on;
        diary dia.txt;
        app.est = tapas_fitModel(app.sim.y,app.sim.u,app.perceptual,app.responsemodel,app.optimalg);
        diary off;
        temp = regexp(fileread('dia.txt'), '\r?\n', 'split');
        app.Label_2.Text = temp;
        delete dia.txt;
        delete dia;
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            global est;
            est=app.est;
            tapas_fit_plotCorr(est);
            
        end

        % Callback function
        function CheckButton_2Pushed(app, event)
            global est;
            est=app.est;
            tapas_fit_plotCorr(est);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            responsefile = uigetfile;      
            app.response = load(responsefile); 
            app.notdoneLabel_9.Text = 'done';
            app.notdoneLabel_9.FontColor= 'green';
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        diary on;
        diary dia.txt;
        app.est = tapas_fitModel(app.response,app.u,app.perceptual,app.responsemodel,app.optimalg);
        diary off;
        temp = regexp(fileread('dia.txt'), '\r?\n', 'split');
        app.Label_2.Text = temp;
        delete dia.txt;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadbinaryInputchooseallfiles_inputtxtLabel = uilabel(app.UIFigure);
            app.LoadbinaryInputchooseallfiles_inputtxtLabel.Text = '1. Load binary Input (choose "all files", --> ..._input.txt); ';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PlotbinaryinputLabel = uilabel(app.UIFigure);
            app.PlotbinaryinputLabel.Text = '2. Plot binary input';
            app.FindButton = uibutton(app.UIFigure, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.FindBayesoptimalparametervaluesLabel = uilabel(app.UIFigure);
            app.FindBayesoptimalparametervaluesLabel.Text = '5. Find Bayes optimal parameter values  ';
            app.LoadResponseLabel = uilabel(app.UIFigure);
            app.LoadResponseLabel.Text = '7. Load Response';
            app.DefaultButton = uibutton(app.UIFigure, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.loadsexample_binary_inputLabel = uilabel(app.UIFigure);
            app.loadsexample_binary_inputLabel.Text = '(loads "example_binary_input")';
            app.ChooseperceptualresponsemodelLabel = uilabel(app.UIFigure);
            app.ChooseperceptualresponsemodelLabel.Text = '3. Choose perceptual/ response model';
            app.PerceptualModelButton = uibutton(app.UIFigure, 'push');
            app.PerceptualModelButton.ButtonPushedFcn = createCallbackFcn(app, @PerceptualModelButtonPushed, true);
            app.PerceptualModelButton.Text = 'Perceptual Model';
            app.ResponseModelButton = uibutton(app.UIFigure, 'push');
            app.ResponseModelButton.ButtonPushedFcn = createCallbackFcn(app, @ResponseModelButtonPushed, true);
            app.ResponseModelButton.Text = 'Response Model';
            app.notdoneLabel_2 = uilabel(app.UIFigure);
            app.notdoneLabel_2.Text = 'not done';
            app.notdoneLabel_3 = uilabel(app.UIFigure);
            app.notdoneLabel_3.Text = 'not done';
            app.DefaultButton_2 = uibutton(app.UIFigure, 'push');
            app.DefaultButton_2.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_2Pushed, true);
            app.DefaultButton_2.Text = 'Default';
            app.loadstapas_hgf_binary_configandLabel = uilabel(app.UIFigure);
            app.loadstapas_hgf_binary_configandLabel.Text = '(loads "tapas_hgf_binary_config"  and  "tapas_bayes_optimal_binary_config") ';
            app.notdoneLabel_4 = uilabel(app.UIFigure);
            app.notdoneLabel_4.Text = 'not done';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.ChooseoptimizationalgorithmLabel = uilabel(app.UIFigure);
            app.ChooseoptimizationalgorithmLabel.Text = '4. Choose optimization algorithm';
            app.DefaultButton_3 = uibutton(app.UIFigure, 'push');
            app.DefaultButton_3.ButtonPushedFcn = createCallbackFcn(app, @DefaultButton_3Pushed, true);
            app.DefaultButton_3.Text = 'Default';
            app.loadstapas_hgf_binary_configandLabel_2 = uilabel(app.UIFigure);
            app.loadstapas_hgf_binary_configandLabel_2.Text = '(loads "tapas_quasinewton_optim_config")';
            app.notdoneLabel_5 = uilabel(app.UIFigure);
            app.notdoneLabel_5.Text = 'not done';
            app.notdoneLabel_6 = uilabel(app.UIFigure);
            app.notdoneLabel_6.Text = 'not done';
            app.notdoneLabel_7 = uilabel(app.UIFigure);
            app.notdoneLabel_7.Text = 'not done';
            app.notdoneLabel_8 = uilabel(app.UIFigure);
            app.notdoneLabel_8.Text = 'not done';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CheckparameteridentifiabilityLabel = uilabel(app.UIFigure);
            app.CheckparameteridentifiabilityLabel.Text = '6.Check parameter identifiability ';
            app.CheckButton = uibutton(app.UIFigure, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.LoadButton_3 = uibutton(app.UIFigure, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.notdoneLabel_9 = uilabel(app.UIFigure);
            app.notdoneLabel_9.Text = 'not done';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CalculateParametersLabel = uilabel(app.UIFigure);
            app.CalculateParametersLabel.Text = '8. Calculate Parameters';
            app.notdoneLabel_10 = uilabel(app.UIFigure);
            app.notdoneLabel_10.Text = 'not done';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end